classdef dtag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dTagDemoUIFigure     matlab.ui.Figure
        ConnectMenu          matlab.ui.container.Menu
        FirmwareMenu         matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        OptionsButton        matlab.ui.control.Button
        FunctionButtonGroup  matlab.ui.container.ButtonGroup
        RESPButton           matlab.ui.control.ToggleButton
        ECGButton            matlab.ui.control.ToggleButton
        PPGButton            matlab.ui.control.ToggleButton
        RightPanel           matlab.ui.container.Panel
        ReadAllButton        matlab.ui.control.Button
        RegEditField         matlab.ui.control.NumericEditField
        RegChangeLabel       matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        RegsMap              matlab.ui.control.Label
        WriteRegisterButton  matlab.ui.control.Button
        ReadRegisterButton   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        tcpHandle = -1;% tcp client handle
    end

    properties (Access = private)
        CurChildApp  = [];     % Current child app
        PlotLine1 ;       % Line object
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
        curReg;

    end
    methods (Access = public)

        function childAppExitCb(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.CurrentColormap = c;

                     
            app.CurChildApp  = []; 
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupCB(app)
            t = readtable('Regs.xls');
            vars = {'Name','Address','Default','Value','Description'};
            n = size(t);
            t = t(1:n(1),vars);
            app.UITable.Data = t;
            app.UITable.Selection = 1;
            app.curReg = 1;
            regName = cell2mat(app.UITable.Data{1,1});
            app.RegChangeLabel.Text = strcat('Reg: ',regName);
            app.RegEditField.Value = hex2dec(cell2mat(app.UITable.Data{1,4}));


        end

        % Menu selected function: ConnectMenu
        function connectCB(app, event)

            if(app.tcpHandle < 0)
                % reset persistent var
                frameDecode([],1);

                add_no={'Address'};
                default={'192.168.3.83'};
                inputIP=inputdlg(add_no,'Input Address',[1 30;],default);
                if(~isempty(inputIP))
                    app.tcpHandle = pnet('tcpconnect',char(inputIP),3338);
                    if app.tcpHandle >= 0
                        %  app.OptionsButton.Enable = 'on';
                        app.WriteRegisterButton.Enable = 'on';
                        app.ReadRegisterButton.Enable = 'on';
                        app.ReadAllButton.Enable = 'on';
                        app.FirmwareMenu.Enable = 'on';
                    else
                        uialert(app.dTagDemoUIFigure,'Tcp server not found','Connect Error');

                    end

                end
            else
                uialert(app.dTagDemoUIFigure,'Tcp server already connected','Tcp Connect');

            end

        end

        % Menu selected function: FirmwareMenu
        function fwUpgradeCB(app, event)
            if(~isempty(app.CurChildApp))
                uialert(app.dTagDemoUIFigure,'A child App is currently running, Please firstly shutdown it','Firmware upgrade Error');
                return;
            end

            selection = uiconfirm(app.dTagDemoUIFigure,'bioSensor firmware upgrade?','Firmware Upgrade',...
                'Icon','warning');
            switch selection
                case 'OK'

                    uiprg = uiprogressdlg(app.dTagDemoUIFigure,'Title','BioSensor firmware updating',...
                        'Message','loading the firmware bin file');
                    % loading firmware file

                    fid = fopen('bioSensor\sensor_ap_dTag.BIN','r');
                    if(fid >= 0)
                        bintxt = fread(fid);
                        fileSz = size(bintxt);
                        fileSz = fileSz(1);
                        if(fileSz ~= 30041)
                            uialert(app.dTagDemoUIFigure,'Content about the file is incorrect','Bin file Error');
                            close(uiprg);
                            return;
                        end

                    else
                        uialert(app.dTagDemoUIFigure,'Read bin file error','Read file Error');
                        close(uiprg);
                        return;
                    end

                    % Requesting BioSensor status
                    % uiprg.Value = 0;
                    uiprg.Message = 'Requesting BioSensor status';
                    parabuf = uint8(1:3);
                    parabuf(1) = uint8(hex2dec('61'));   % BIO_FW_UPGRADE_REQ    0X61
                    parabuf(2) = 0;
                    parabuf(3) = 0;
                    [resType,resData,rlen] = startSensorFunction(app.tcpHandle,'H_CMD_FIRMWARE_BIO',parabuf,1,10);
                    if(rlen < 3)
                        uialert(app.dTagDemoUIFigure,'Requesting BioSensor status error','BioSensor status');
                        close(uiprg);
                        return;
                    else
                        if resType == 0 && resData(1) == dTagConst('H_CMD_FIRMWARE_BIO') && (resData(4) == 1)
                            uiprg.Message = 'Programming...';
                            parabuf = uint8(1:131); % 1 byte para+2 bytes offset+ 128 bytes firmware data
                            parabuf(1) = uint8(hex2dec('6f')); % BIO_FW_PROGRAM 0x6f
                            for n=1:232
                                tmp=(n-1)*64;
                                parabuf(2) = uint8((bitshift(tmp,-8)));
                                parabuf(3) = uint8(bitand(tmp,255));
                                parabuf(4:131) = uint8(bintxt(1+(n-1)*128 : n*128));
                                [resType,resData,rlen] = startSensorFunction(app.tcpHandle,'H_CMD_FIRMWARE_BIO',parabuf,1,1);
                                if(rlen > 3)
                                    if resType == 0 && resData(1) == dTagConst('H_CMD_FIRMWARE_BIO') && (resData(4) == 129)
                                        uiprg.Value = n/232;
                                    else
                                        uialert(app.dTagDemoUIFigure,'Program error','Firmware program');
                                        close(uiprg);
                                        return;
                                    end
                                else
                                    uialert(app.dTagDemoUIFigure,'Program error','Firmware program');
                                    close(uiprg);
                                    return;
                                end
                            end
                            uialert(app.dTagDemoUIFigure,'Program ok,please reset dTag','Firmware program',...
                                'Icon','success');
                        else
                            uialert(app.dTagDemoUIFigure,'Requesting BioSensor status error','BioSensor status');
                        end                        
                    end
                    close(uiprg);
                        return;
                case 'Cancel'
                    return;
            end

        end

        % Button pushed function: OptionsButton
        function StartBTNCB(app, event)
            
            if(~isempty(app.CurChildApp))
                uialert(app.dTagDemoUIFigure,'A child App is currently running, Please firstly shutdown it','Firmware upgrade Error');
                return;
            end

            % Disable Plot Options button while child app is open
            switch app.FunctionButtonGroup.SelectedObject
                case app.PPGButton
                    app.CurChildApp = PPGApp(app, app.CurrentSize, app.CurrentColormap);

                case app.ECGButton
                    app.CurChildApp = ECGApp(app, app.CurrentSize, app.CurrentColormap);

            end
            % Open the options dialog and pass inputs

        end

        % Close request function: dTagDemoUIFigure
        function MainAppCloseRequest(app, event)
            if(~isempty(app.CurChildApp))
                delete(app.CurChildApp)
            end
            if(app.tcpHandle >= 0)
                pnet(app.tcpHandle,'close')
            end
            delete(app)

        end

        % Selection changed function: UITable
        function RegMapSelectionChgCb(app, event)
            removeStyle(app.UITable);
            app.curReg = app.UITable.Selection;
            regName = cell2mat(app.UITable.Data{app.curReg,1});
            app.RegChangeLabel.Text = strcat('Reg: ',regName);
            app.RegEditField.Value = hex2dec(cell2mat(app.UITable.Data{app.curReg,4}));

        end

        % Value changed function: RegEditField
        function RegChangedCb(app, event)
            %   value = app.RegEditField.Value;

        end

        % Button pushed function: WriteRegisterButton
        function RegWriteBtnCb(app, event)
            if(~isempty(app.CurChildApp))
                uialert(app.dTagDemoUIFigure,'A child App is currently running, Please firstly shutdown it','Firmware upgrade Error');
                return;
            end
            if(app.tcpHandle < 0)
                uialert(app.dTagDemoUIFigure,'Never connected a TCP server,please firstly connect it','TCP server Error');
                return;
            end
            app.OptionsButton.Enable = 'off';
            app.WriteRegisterButton.Enable = 'off';
            app.ReadRegisterButton.Enable = 'off';
            app.ReadAllButton.Enable = 'off';
            app.RegEditField.Editable = 'off';
            if (app.curReg > dTagConst('REG_ONLY_RD_MAX'))
                regAddr = hex2dec(cell2mat(app.UITable.Data{app.curReg,2}));
                parabuf = uint8(1:3);
                parabuf(1) = uint8(regAddr);
                parabuf(2) = uint8(app.RegEditField.Value);
                parabuf(3) = 0;

                [resType,resData,rlen] = startSensorFunction(app.tcpHandle,'H_CMD_WRITE_REG',parabuf,1,1);

                if rlen > 3
                    if resType == 0 && resData(1) == dTagConst('H_CMD_WRITE_REG')
                        app.UITable.Data{app.curReg,4} = {strcat('0x',dec2hex(app.RegEditField.Value))};
                        s = uistyle('BackgroundColor','b');
                        addStyle(app.UITable,s,'cell',[app.curReg,4]);
                    end
                else
                    error('tcp read error')

                end
            end
            app.RegEditField.Editable = 'on';
            app.OptionsButton.Enable = 'on';
            app.WriteRegisterButton.Enable = 'on';
            app.ReadRegisterButton.Enable = 'on';
            app.ReadAllButton.Enable = 'on';

        end

        % Button pushed function: ReadRegisterButton
        function RegReadBtnCb(app, event)
            if(~isempty(app.CurChildApp))
                uialert(app.dTagDemoUIFigure,'A child App is currently running, Please firstly shutdown it','Firmware upgrade Error');
                return;
            end
            
            if(app.tcpHandle < 0)
                uialert(app.dTagDemoUIFigure,'Never connected a TCP server,please firstly connect it','TCP server Error');
                return;
            end
            app.OptionsButton.Enable = 'off';
            app.WriteRegisterButton.Enable = 'off';
            app.ReadRegisterButton.Enable = 'off';
            app.ReadAllButton.Enable = 'off';

            regAddr = hex2dec(cell2mat(app.UITable.Data{app.curReg,2}));
            [resType,resData,rlen] = readSensorReg(app.tcpHandle,regAddr);
            if rlen > 3
                if resType == 0
                    app.UITable.Data{app.curReg,4} = {strcat('0x',dec2hex(resData(4)))};
                    s = uistyle('BackgroundColor','g');
                    addStyle(app.UITable,s,'cell',[app.curReg,4]);
                end
            else
                error('tcp read error')

            end
            app.OptionsButton.Enable = 'on';
            app.WriteRegisterButton.Enable = 'on';
            app.ReadRegisterButton.Enable = 'on';
            app.ReadAllButton.Enable = 'on';

        end

        % Button pushed function: ReadAllButton
        function ReadAllBtnCb(app, event)
            if(~isempty(app.CurChildApp))
                uialert(app.dTagDemoUIFigure,'A child App is currently running, Please firstly shutdown it','Firmware upgrade Error');
                return;
            end
            if(app.tcpHandle < 0)
                uialert(app.dTagDemoUIFigure,'Never connected a TCP server,please firstly connect it','TCP server Error');
                return;
            end
            app.OptionsButton.Enable = 'off';
            app.WriteRegisterButton.Enable = 'off';
            app.ReadRegisterButton.Enable = 'off';
            app.ReadAllButton.Enable = 'off';
            tsize = size(app.UITable.Data);
            s = uistyle('BackgroundColor','g');

            for n = 1:tsize(1)
                regAddr = hex2dec(cell2mat(app.UITable.Data{n,2}));
                [resType,resData,rlen] = readSensorReg(app.tcpHandle,regAddr);
                if rlen > 3
                    if resType == 0
                        app.UITable.Data{n,4} = {strcat('0x',dec2hex(resData(4)))};

                        addStyle(app.UITable,s,'cell',[n,4]);
                    end
                else
                    uialert(app.dTagDemoUIFigure,'Read timeout','Read register');
                    break;

                end
            end
            app.OptionsButton.Enable = 'on';
            app.WriteRegisterButton.Enable = 'on';
            app.ReadRegisterButton.Enable = 'on';
            app.ReadAllButton.Enable = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.dTagDemoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {566, 566};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {138, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.dTagDemoUIFigure = uifigure('Visible', 'off');
            app.dTagDemoUIFigure.Name = 'dTag Demo';
            app.dTagDemoUIFigure.Icon = fullfile(pathToMLAPP, 'dTag.png');
            app.dTagDemoUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.dTagDemoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.dTagDemoUIFigure.Tag = 'Firm';
            app.ConnectMenu = uimenu(app.dTagDemoUIFigure);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @connectCB, true);
            app.ConnectMenu.Text = 'Connect';
            app.FirmwareMenu = uimenu(app.dTagDemoUIFigure);
            app.FirmwareMenu.MenuSelectedFcn = createCallbackFcn(app, @fwUpgradeCB, true);
            app.FirmwareMenu.Text = 'Firmware';
            app.GridLayout = uigridlayout(app.dTagDemoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionButtonGroup = uibuttongroup(app.LeftPanel);
            app.FunctionButtonGroup.Title = 'Function';
            app.PPGButton = uitogglebutton(app.FunctionButtonGroup);
            app.PPGButton.Text = 'PPG';
            app.PPGButton.Value = true;
            app.ECGButton = uitogglebutton(app.FunctionButtonGroup);
            app.ECGButton.Text = 'ECG';
            app.RESPButton = uitogglebutton(app.FunctionButtonGroup);
            app.RESPButton.Text = 'RESP';
            app.OptionsButton = uibutton(app.LeftPanel, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @StartBTNCB, true);
            app.OptionsButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Tag = 'Regs';
            app.ReadRegisterButton = uibutton(app.RightPanel, 'push');
            app.ReadRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegReadBtnCb, true);
            app.ReadRegisterButton.Tooltip = {'Read register'};
            app.ReadRegisterButton.Text = 'Read Register';
            app.WriteRegisterButton = uibutton(app.RightPanel, 'push');
            app.WriteRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegWriteBtnCb, true);
            app.WriteRegisterButton.Tooltip = {'Write register'};
            app.WriteRegisterButton.Text = 'Write Register';
            app.RegsMap = uilabel(app.RightPanel);
            app.RegsMap.Text = 'Regsister Map:';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Name'; 'Address'; 'Default'; 'Value'; 'Description'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @RegMapSelectionChgCb, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Tooltip = {'Regsister Map'};
            app.UITable.Tag = 'Regsister Map';
            app.RegChangeLabel = uilabel(app.RightPanel);
            app.RegChangeLabel.Text = 'RegChangeLabel';
            app.RegEditField = uieditfield(app.RightPanel, 'numeric');
            app.RegEditField.Limits = [0 255];
            app.RegEditField.ValueDisplayFormat = '%02X';
            app.RegEditField.ValueChangedFcn = createCallbackFcn(app, @RegChangedCb, true);
            app.RegEditField.Tooltip = {'Input a decimal ,automatically converted to hex'};
            app.ReadAllButton = uibutton(app.RightPanel, 'push');
            app.ReadAllButton.ButtonPushedFcn = createCallbackFcn(app, @ReadAllBtnCb, true);
            app.ReadAllButton.Tooltip = {'Read register'};
            app.ReadAllButton.Text = 'Read All';
            % Show the figure after all components are created
            app.dTagDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dtag

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.dTagDemoUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupCB)
            else

                % Focus the running singleton app
                figure(runningApp.dTagDemoUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dTagDemoUIFigure)
        end
    end
end