classdef ECGApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SampleFreqDropDown       matlab.ui.control.DropDown
        SampleFreqDropDownLabel  matlab.ui.control.Label
        RegOpGainEditField       matlab.ui.control.NumericEditField
        RegOpGainEditFieldLabel  matlab.ui.control.Label
        RegOpSw2EditField        matlab.ui.control.NumericEditField
        RegOpSw2EditFieldLabel   matlab.ui.control.Label
        RegOpSw1EditField        matlab.ui.control.NumericEditField
        RegOpSw1EditFieldLabel   matlab.ui.control.Label
        RegOpSw0EditField        matlab.ui.control.NumericEditField
        RegOpSw0EditFieldLabel   matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        parentApp   % Main app object
        FreshTimer   % Timer object
        WavePlot      % Line object
        MaxY = uint32(0);
        MinY = uint32(hex2dec('ffffff'));

    end

    methods (Access = private)

        function FreshTimerFcn(app,~,~)

            raw=pnet(app.parentApp.tcpHandle,'read' ,'uint8' ,'noblock');
            if ~isempty(raw)
                [~,rxdata,rlen] = frameDecode(raw,0);

                plotloc = 1;
                while(rlen > 5)
                    % Update YData in plot

                    ydata = app.WavePlot.YData;
                    ydata = circshift(ydata,1);
                    tmpdata = uint32(rxdata(plotloc+2));
                    tmpdata = bitshift(tmpdata,8);
                    tmpdata = tmpdata + uint32(rxdata(plotloc+1));
                    tmpdata = bitshift(tmpdata,8);
                    tmpdata = tmpdata + uint32(rxdata(plotloc));
                    if  app.MaxY < tmpdata
                        app.MaxY = tmpdata;
                    end
                    if  app.MinY > tmpdata
                        app.MinY = tmpdata;
                    end

                    if app.MaxY > app.MinY

                        ydata(1) = (double(tmpdata) - double(app.MinY))/(double(app.MaxY) - double(app.MinY))*1000;
                    else
                        ydata(1) = 0;
                    end
                    %    disp(ydata(1))
                    app.WavePlot.YData = ydata;
                    rlen = rlen-4;
                    plotloc = plotloc + 4;
                end
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, sz, c)
            % Store main app in property for CloseRequestFcn to use
            app.parentApp = mainapp;

            % Configure x- and y- axis
            app.UIAxes.XLim = [0 500];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 1000];

            % Initial plot is all zeros
            app.WavePlot = plot(app.UIAxes,0:500,zeros(1,501));

            app.FreshTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 100ms
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.FreshTimerFcn);      % Specify callback function

            if (app.parentApp.tcpHandle < 0)
                app.StartButton.Enable = "off";
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            if strcmp(app.FreshTimer.Running, 'on')
                stopSensorFunction(app.parentApp.tcpHandle,'H_CMD_ECG');
                stop(app.FreshTimer);                
            end
            
            
            % Call main app's public function
            childAppExitCb(app.parentApp, 0, 0);


            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
           if strcmp(app.FreshTimer.Running, 'on')
                stopSensorFunction(app.parentApp.tcpHandle,'H_CMD_ECG');
                stop(app.FreshTimer);                
            end
            
            % Enable the Plot Opions button in main app
            childAppExitCb(app.parentApp, 0, 0);
            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: StartButton
        function StartBtnCb(app, event)

            if (app.parentApp.tcpHandle < 0)
                uialert(app.UIFigure,'Tcp server is not Connected','Connect Error');
                return;
            end

            if strcmp(app.FreshTimer.Running, 'off')

                app.StartButton.Enable = "off";
                parabuf = uint8(1:1+dTagConst('REG_MAP_MAX'));
                parabuf(1)= uint8(dTagConst('DEV_FUN_START')+dTagConst('DATA_RAW'));
                parabuf(1+dTagConst('REG_MAP_CFMODE'))= uint8(app.SampleFreqDropDown.Value);

                parabuf(1+dTagConst('REG_MAP_OPA_SW0'))= uint8(app.RegOpSw0EditField.Value);
                parabuf(1+dTagConst('REG_MAP_OPA_SW1'))= uint8(app.RegOpSw1EditField.Value);
                parabuf(1+dTagConst('REG_MAP_OPA_SW2'))= uint8(app.RegOpSw2EditField.Value);

                parabuf(1+dTagConst('REG_MAP_OPA_GAIN'))= uint8(app.RegOpGainEditField.Value);
                parabuf(1+dTagConst('REG_MAP_CFUN'))= uint8(2); % BIO_FUN_ECG

                startSensorFunction(app.parentApp.tcpHandle,'H_CMD_ECG',parabuf,1,1.5);
                app.StopButton.Enable = "on";
                start(app.FreshTimer); % Note:Be sure to start the timer last!

            end

        end

        % Button pushed function: StopButton
        function StopBtnCb(app, event)
            if strcmp(app.FreshTimer.Running, 'on')
                stop(app.FreshTimer); % Note:Be sure to stop the timer first!
                
                stopSensorFunction(app.parentApp.tcpHandle,'H_CMD_ECG');
                
                app.StartButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PPG TEST';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'dTag.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartBtnCb, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopBtnCb, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.RegOpSw0EditFieldLabel = uilabel(app.UIFigure);
            app.RegOpSw0EditFieldLabel.Text = 'RegOpSw0';
            app.RegOpSw0EditField = uieditfield(app.UIFigure, 'numeric');
            app.RegOpSw0EditField.Limits = [0 255];
            app.RegOpSw0EditField.RoundFractionalValues = 'on';
            app.RegOpSw0EditField.ValueDisplayFormat = '%02X';
            app.RegOpSw0EditField.Value = 1;
            app.RegOpSw1EditFieldLabel = uilabel(app.UIFigure);
            app.RegOpSw1EditFieldLabel.Text = 'RegOpSw1';
            app.RegOpSw1EditField = uieditfield(app.UIFigure, 'numeric');
            app.RegOpSw1EditField.Limits = [0 255];
            app.RegOpSw1EditField.RoundFractionalValues = 'on';
            app.RegOpSw1EditField.ValueDisplayFormat = '%02X';
            app.RegOpSw1EditField.Value = 64;
            app.RegOpSw2EditFieldLabel = uilabel(app.UIFigure);
            app.RegOpSw2EditFieldLabel.Text = 'RegOpSw2';
            app.RegOpSw2EditField = uieditfield(app.UIFigure, 'numeric');
            app.RegOpSw2EditField.Limits = [0 255];
            app.RegOpSw2EditField.RoundFractionalValues = 'on';
            app.RegOpSw2EditField.ValueDisplayFormat = '%02X';
            app.RegOpGainEditFieldLabel = uilabel(app.UIFigure);
            app.RegOpGainEditFieldLabel.Text = 'RegOpGain';
            app.RegOpGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegOpGainEditField.Limits = [0 255];
            app.RegOpGainEditField.RoundFractionalValues = 'on';
            app.RegOpGainEditField.ValueDisplayFormat = '%02X';
            app.RegOpGainEditField.Value = 16;
            app.SampleFreqDropDownLabel = uilabel(app.UIFigure);
            app.SampleFreqDropDownLabel.Text = 'Sample Freq';
            app.SampleFreqDropDown = uidropdown(app.UIFigure);
            app.SampleFreqDropDown.Items = {'250HZ', '300HZ', '500HZ', '1kHZ'};
            app.SampleFreqDropDown.ItemsData = [0 1 2 3];
            app.SampleFreqDropDown.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECGApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end